classdef Sampling < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        UIAxes                       matlab.ui.control.UIAxes
        UIAxes2                      matlab.ui.control.UIAxes
        ByAbderrahimBELISSAOUILabel  matlab.ui.control.Label
        SignalFrequencyHzLabel       matlab.ui.control.Label
        SignalFrequencyHzSlider      matlab.ui.control.Slider
        SamplingRateHzLabel          matlab.ui.control.Label
        SamplingRateHzEditField      matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
    end
    

    methods (Access = private)

        % Value changed function: SignalFrequencyHzSlider
        function SignalFrequencyHzSliderValueChanged(app, event)
            %% Signal parameters
            Fc = app.SignalFrequencyHzSlider.Value;
            Fs = app.SamplingRateHzEditField.Value ;
          
           
            time = 0:1/Fs:1-1/Fs; 
            Sig = sin( 2*pi*Fc*time) ;
            plot(app.UIAxes, time, Sig , 'b')

            %% Power spectrum
            N = length(Sig); 
            y = fft(Sig);
            
            P2 = abs(y/N);
            P1 = P2(1:N/2+1);
            P1(2:end-1) = 2*P1(2:end-1);
                
            f = Fs*(0:(N/2))/N;     
            plot(app.UIAxes2, f,P1, 'r');
            
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Signal')
            xlabel(app.UIAxes, 'Time (V)')
            ylabel(app.UIAxes, 'Amplitude (V)')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, ' Spectrum of signal')
            xlabel(app.UIAxes2, 'Frequency (Hz)')
            ylabel(app.UIAxes2, 'FFT Magnitude ')
            app.ByAbderrahimBELISSAOUILabel = uilabel(app.UIFigure);
            app.ByAbderrahimBELISSAOUILabel.Text = 'By Abderrahim BELISSAOUI';
            app.SignalFrequencyHzLabel = uilabel(app.UIFigure);
            app.SignalFrequencyHzLabel.Text = 'Signal Frequency (Hz)';
            app.SignalFrequencyHzSlider = uislider(app.UIFigure);
            app.SignalFrequencyHzSlider.ValueChangedFcn = createCallbackFcn(app, @SignalFrequencyHzSliderValueChanged, true);
            app.SamplingRateHzLabel = uilabel(app.UIFigure);
            app.SamplingRateHzLabel.Text = 'Sampling Rate (Hz)';
            app.SamplingRateHzEditField = uieditfield(app.UIFigure, 'numeric');
        end
    end

    methods (Access = public)

        % Construct app
        function app = Sampling

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end